classdef robotcontrolfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        SendButton          matlab.ui.control.Button
        EditField_9         matlab.ui.control.NumericEditField
        FLabel              matlab.ui.control.Label
        Joint3Label         matlab.ui.control.Label
        Joint4Label         matlab.ui.control.Label
        Joint5Label         matlab.ui.control.Label
        Slider_5            matlab.ui.control.Slider
        Slider_4            matlab.ui.control.Slider
        Slider_3            matlab.ui.control.Slider
        Slider_2            matlab.ui.control.Slider
        Joint2Label         matlab.ui.control.Label
        GripperSwitchLabel  matlab.ui.control.Label
        GripperSwitch       matlab.ui.control.Switch
        EditField_8         matlab.ui.control.NumericEditField
        EditField_7         matlab.ui.control.NumericEditField
        EditField_6         matlab.ui.control.NumericEditField
        ZLabel              matlab.ui.control.Label
        YLabel              matlab.ui.control.Label
        XLabel              matlab.ui.control.Label
        InverseLabel        matlab.ui.control.Label
        EditField_5         matlab.ui.control.NumericEditField
        EditField_4         matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        EditField           matlab.ui.control.NumericEditField
        Slider              matlab.ui.control.Slider
        Joint1Label         matlab.ui.control.Label
        ForwardLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        ao=[]
        s1o=[]
        s2o=[]
        s3o=[]
        s4o=[]
        s5o=[]
        s6o=[]
        s1=[];
        s2=[];
        s3=[];
        s4=[];
        s5=[];
        x=[];
        y=[];
        z=[];
        p=[];
        f=[];
        i=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slider.Value=0;
            app.Slider_2.Value=0;
            app.Slider_3.Value=0;
            app.Slider_4.Value=0;
            app.Slider_5.Value=0;
            app.EditField.Value=0;
            app.EditField_2.Value=0;
            app.EditField_3.Value=0;
            app.EditField_4.Value=0;
            app.EditField_5.Value=0;
            if ~isempty(app.ao)
                clear(app.ao)
            end
            app.ao=arduino();
            app.s1o=servo(app.ao,'D3');
            app.s2o=servo(app.ao,'D4');
            app.s3o=servo(app.ao,'D5');
            app.s4o=servo(app.ao,'D6');
            app.s5o=servo(app.ao,'D7');
            app.s6o=servo(app.ao,'D8');
            writePosition(app.s1o,0.5);
            writePosition(app.s2o,0.53);
            writePosition(app.s3o,0.94);
            writePosition(app.s4o,0);
            writePosition(app.s5o,0.56);
            writePosition(app.s6o,0);
            app.s1=0;
            app.s2=0;
            app.s3=0;
            app.s4=0;
            app.s5=0;
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: Slider
        function Slider1ValueChanged(app, event)
            app.EditField.Value = round(app.Slider.Value,2);
            app.s1=app.Slider.Value;
            s11=(app.Slider.Value+90)/180;
            writePosition(app.s1o,s11)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.EditField_2.Value = round(app.Slider_2.Value,2);
            app.s2=app.Slider_2.Value;
            s21=(app.Slider_2.Value+95)/180;
            writePosition(app.s2o,s21)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
           app.EditField_3.Value = round(app.Slider_3.Value,2);
           app.s3=app.Slider_3.Value;
            s31=(170-app.Slider_3.Value)/180;
            writePosition(app.s3o,s31)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.EditField_4.Value = round(app.Slider_4.Value,2);
           app.s4=app.Slider_4.Value;
            s41=app.Slider_4.Value/180;
            writePosition(app.s4o,s41)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.EditField_5.Value = round(app.Slider_5.Value,2);
            app.s5=app.Slider_5.Value;
            s51=(app.Slider_5.Value+100)/180;
            writePosition(app.s5o,s51)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Slider.Value = round(app.EditField.Value,2);
            app.s1=app.EditField.Value;
            s11=(app.EditField.Value+90)/180;
            writePosition(app.s1o,s11)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.Slider_2.Value = round(app.EditField_2.Value,2);
            app.s2=app.EditField_2.Value;
            s21=(app.EditField_2.Value+95)/180;
            writePosition(app.s2o,s21)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.Slider_3.Value = round(app.EditField_3.Value,2);
            app.s3=app.EditField_3.Value;
            s31=(170-app.EditField_3.Value)/180;
            writePosition(app.s3o,s31)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
           app.Slider_4.Value = round(app.EditField_4.Value,2);
         app.s4=app.EditField_4.Value;
            s41=app.EditField_4.Value/180;
            writePosition(app.s4o,s41)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.Slider_5.Value = round(app.EditField_5.Value,2);
           app.s5=app.EditField_5.Value;
            s51=(app.EditField_5.Value+100)/180;
            writePosition(app.s5o,s51)
            app.f=forward1([app.s1 app.s2 app.s3 app.s4 app.s5]);
            app.x=app.f(1,4);
            app.y=app.f(2,4);
            app.z=app.f(3,4);
            app.p=app.s2+app.s3+app.s5;
            app.EditField_6.Value=round(app.x,2);
            app.EditField_7.Value=round(app.y,2);
            app.EditField_8.Value=round(app.z,2);
            app.EditField_9.Value=round(app.p,2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: GripperSwitch
        function GripperSwitchValueChanged(app, event)
            value = app.GripperSwitch.Value;
            switch value
                case 'Open'
                writePosition(app.s6o,0)
                case 'Close'
                writePosition(app.s6o,0.5)
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.i=inverse5([app.EditField_6.Value app.EditField_7.Value app.EditField_8.Value app.EditField_9.Value]);
            app.s1=round(app.i(1,1),2);
            app.Slider.Value=app.s1;
            app.EditField.Value=app.s1;
            s10=(app.s1+90)/180;
            writePosition(app.s1o,s10)
            app.s2=round(app.i(1,2),2);
            app.Slider_2.Value=app.s2;
            app.EditField_2.Value=app.s2;
            s20=(app.s2+95)/180;
            writePosition(app.s2o,s20)
            app.s3=round(app.i(1,3),2);
            app.Slider_3.Value=app.s3;
            app.EditField_3.Value=app.s3;
            s30=(170-app.s3)/180;
            writePosition(app.s3o,s30)
            app.s4=round(app.i(1,4),2);
            app.Slider_4.Value=app.s4;
            app.EditField_4.Value=app.s4;
            s40=app.s2/180;
            writePosition(app.s4o,s40)
            app.s5=round(app.i(1,5),2);
            app.Slider_5.Value=app.s5;
            app.EditField_5.Value=app.s5;
            s50=(app.s5+100)/180;
            writePosition(app.s5o,s50)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ForwardLabel = uilabel(app.GridLayout);
            app.ForwardLabel.Text = 'Forward';
            app.Joint1Label = uilabel(app.GridLayout);
            app.Joint1Label.Text = 'Joint 1';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [-90 90];
            app.Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider.Value = 60;
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Limits = [-90 90];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.Limits = [-90 90];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.Limits = [0 180];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.Limits = [0 180];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5.Limits = [-90 90];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.InverseLabel = uilabel(app.GridLayout);
            app.InverseLabel.Text = 'Inverse';
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.2f';
            app.EditField_7 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%.2f';
            app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_8.ValueDisplayFormat = '%.2f';
            app.GripperSwitch = uiswitch(app.GridLayout, 'slider');
            app.GripperSwitch.Items = {'Open', 'Close'};
            app.GripperSwitch.ValueChangedFcn = createCallbackFcn(app, @GripperSwitchValueChanged, true);
            app.GripperSwitch.Value = 'Open';
            app.GripperSwitchLabel = uilabel(app.GridLayout);
            app.GripperSwitchLabel.Text = 'Gripper';
            app.Joint2Label = uilabel(app.GridLayout);
            app.Joint2Label.Text = 'Joint 2';
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.Limits = [-90 80];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.Limits = [0 170];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 90;
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.Limits = [0 180];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 144;
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.Limits = [-90 80];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Joint5Label = uilabel(app.GridLayout);
            app.Joint5Label.Text = 'Joint 5';
            app.Joint4Label = uilabel(app.GridLayout);
            app.Joint4Label.Text = 'Joint 4';
            app.Joint3Label = uilabel(app.GridLayout);
            app.Joint3Label.Text = 'Joint 3';
            app.FLabel = uilabel(app.GridLayout);
            app.FLabel.Text = 'F';
            app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.2f';
            app.SendButton = uibutton(app.GridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotcontrolfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end