classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        EditField_20               matlab.ui.control.EditField
        rtLabel                    matlab.ui.control.Label
        EditField_19               matlab.ui.control.EditField
        EditField_18               matlab.ui.control.EditField
        YrLabel                    matlab.ui.control.Label
        PrLabel                    matlab.ui.control.Label
        EditField_17               matlab.ui.control.EditField
        RrLabel                    matlab.ui.control.Label
        AxisButtonGroup            matlab.ui.container.ButtonGroup
        ZButton                    matlab.ui.control.RadioButton
        YButton                    matlab.ui.control.RadioButton
        XButton                    matlab.ui.control.RadioButton
        Tree                       matlab.ui.container.CheckBoxTree
        Joint1Node                 matlab.ui.container.TreeNode
        Joint2Node                 matlab.ui.container.TreeNode
        Joint3Node                 matlab.ui.container.TreeNode
        Joint4Node                 matlab.ui.container.TreeNode
        Joint5Node                 matlab.ui.container.TreeNode
        EditField_16               matlab.ui.control.EditField
        stLabel                    matlab.ui.control.Label
        ChooseButtonGroup          matlab.ui.container.ButtonGroup
        LinkButton                 matlab.ui.control.RadioButton
        JointButton                matlab.ui.control.RadioButton
        ButtonGroup                matlab.ui.container.ButtonGroup
        ParabolicButton            matlab.ui.control.RadioButton
        CycloidalButton            matlab.ui.control.RadioButton
        CubicButton                matlab.ui.control.RadioButton
        EditField_15               matlab.ui.control.EditField
        EditField_14               matlab.ui.control.EditField
        EditField_13               matlab.ui.control.EditField
        EditField_12               matlab.ui.control.NumericEditField
        TotalTimeLabel_2           matlab.ui.control.Label
        ZsLabel                    matlab.ui.control.Label
        YsLabel                    matlab.ui.control.Label
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        AngularAccelerationButton  matlab.ui.control.RadioButton
        AngularVelocityButton      matlab.ui.control.RadioButton
        AccelerationButton         matlab.ui.control.RadioButton
        VelocityButton             matlab.ui.control.RadioButton
        PositionButton             matlab.ui.control.RadioButton
        UploadButton               matlab.ui.control.Button
        XsLabel                    matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        q5Label_2                  matlab.ui.control.Label
        q4Label_2                  matlab.ui.control.Label
        q3Label_2                  matlab.ui.control.Label
        q2Label_2                  matlab.ui.control.Label
        q5Label                    matlab.ui.control.Label
        q4Label                    matlab.ui.control.Label
        q3Label                    matlab.ui.control.Label
        q2Label                    matlab.ui.control.Label
        q1Label_2                  matlab.ui.control.Label
        ShowgraphsButton           matlab.ui.control.Button
        CalculateButton            matlab.ui.control.Button
        EditField_11               matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        EditField_9                matlab.ui.control.NumericEditField
        EditField_8                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        TotalTimeLabel             matlab.ui.control.Label
        q1Label                    matlab.ui.control.Label
        GoalPointLabel             matlab.ui.control.Label
        StartPointLabel            matlab.ui.control.Label
        ChoosethetrajectorytechniqueButtonGroup  matlab.ui.container.ButtonGroup
        CartesianSpaceButton       matlab.ui.control.RadioButton
        JointSpaceButton           matlab.ui.control.RadioButton
        TrajectoryPlanningLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        aj=[];
        vj=[];
        pj=[];
        px=[];
        py=[];
        pz=[];
        vx=[];
        vy=[];
        vz=[];
        ax=[];
        ay=[];
        az=[];
        wx=[];
        wy=[];
        wz=[];
        lx=[];
        ly=[];
        lz=[];
        ts=[];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: 
        % ChoosethetrajectorytechniqueButtonGroup
        function ChoosethetrajectorytechniqueButtonGroupSelectionChanged(app, event)
            s = app.ChoosethetrajectorytechniqueButtonGroup.SelectedObject.Text;
            switch s
                case 'Joint Space'
                    app.StartPointLabel.Visible='on';
                    app.GoalPointLabel.Visible='on';
                    app.q1Label.Visible='on';
                    app.q2Label.Visible='on';
                    app.q3Label.Visible='on';
                    app.q4Label.Visible='on';
                    app.q5Label.Visible='on';
                    app.q1Label_2.Visible='on';
                    app.q2Label_2.Visible='on';
                    app.q3Label_2.Visible='on';
                    app.q4Label_2.Visible='on';
                    app.q5Label_2.Visible='on';
                    app.EditField.Visible='on';
                    app.EditField.Enable='on';
                    app.EditField_2.Visible='on';
                    app.EditField_2.Enable='on';
                    app.EditField_3.Visible='on';
                    app.EditField_3.Enable='on';
                    app.EditField_4.Visible='on';
                    app.EditField_4.Enable='on';
                    app.EditField_5.Visible='on';
                    app.EditField_5.Enable='on';
                    app.EditField_6.Visible='on';
                    app.EditField_6.Enable='on';
                    app.EditField_7.Visible='on';
                    app.EditField_7.Enable='on';
                    app.EditField_8.Visible='on';
                    app.EditField_8.Enable='on';
                    app.EditField_9.Visible='on';
                    app.EditField_9.Enable='on';
                    app.EditField_10.Visible='on';
                    app.EditField_10.Enable='on';
                    app.EditField_11.Visible='on';
                    app.EditField_11.Enable='on';
                    app.TotalTimeLabel.Visible='on';
                    app.ButtonGroup.Visible='on';
                    app.ButtonGroup.Enable='on';
                    app.XsLabel.Visible='off';
                    app.YsLabel.Visible='off';
                    app.ZsLabel.Visible='off';
                    app.stLabel.Visible='off';
                    app.RrLabel.Visible='off';
                    app.PrLabel.Visible='off';
                    app.YrLabel.Visible='off';
                    app.rtLabel.Visible='off';
                    app.EditField_12.Visible='off';
                    app.EditField_12.Enable='off';
                    app.EditField_13.Visible='off';
                    app.EditField_13.Enable='off';
                    app.EditField_14.Visible='off';
                    app.EditField_14.Enable='off';
                    app.EditField_15.Visible='off';
                    app.EditField_16.Visible='off';
                    app.EditField_15.Enable='off';
                    app.EditField_16.Enable='off';
                    app.EditField_17.Visible='off';
                    app.EditField_17.Enable='off';
                    app.EditField_18.Visible='off';
                    app.EditField_18.Enable='off';
                    app.EditField_19.Visible='off';
                    app.EditField_19.Enable='off';
                    app.EditField_20.Visible='off';
                    app.EditField_20.Enable='off';
                    app.TotalTimeLabel_2.Visible='off';
                case 'Cartesian Space'
                    app.StartPointLabel.Visible='off';
                    app.GoalPointLabel.Visible='off';
                    app.q1Label.Visible='off';
                    app.q2Label.Visible='off';
                    app.q3Label.Visible='off';
                    app.q4Label.Visible='off';
                    app.q5Label.Visible='off';
                    app.q1Label_2.Visible='off';
                    app.q2Label_2.Visible='off';
                    app.q3Label_2.Visible='off';
                    app.q4Label_2.Visible='off';
                    app.q5Label_2.Visible='off';
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    app.EditField_2.Visible='off';
                    app.EditField_2.Enable='off';
                    app.EditField_3.Visible='off';
                    app.EditField_3.Enable='off';
                    app.EditField_4.Visible='off';
                    app.EditField_4.Enable='off';
                    app.EditField_5.Visible='off';
                    app.EditField_5.Enable='off';
                    app.EditField_6.Visible='off';
                    app.EditField_6.Enable='off';
                    app.EditField_7.Visible='off';
                    app.EditField_7.Enable='off';
                    app.EditField_8.Visible='off';
                    app.EditField_8.Enable='off';
                    app.EditField_9.Visible='off';
                    app.EditField_9.Enable='off';
                    app.EditField_10.Visible='off';
                    app.EditField_10.Enable='off';
                    app.EditField_11.Visible='off';
                    app.EditField_11.Enable='off';
                    app.TotalTimeLabel.Visible='off';
                    app.ButtonGroup.Visible='off';
                    app.ButtonGroup.Enable='off';
                    app.XsLabel.Visible='on';
                    app.YsLabel.Visible='on';
                    app.ZsLabel.Visible='on';
                    app.stLabel.Visible='on';
                    app.RrLabel.Visible='on';
                    app.YrLabel.Visible='on';
                    app.PrLabel.Visible='on';
                    app.rtLabel.Visible='on';
                    app.EditField_12.Visible='on';
                    app.EditField_12.Enable='on';
                    app.EditField_13.Visible='on';
                    app.EditField_13.Enable='on';
                    app.EditField_14.Visible='on';
                    app.EditField_14.Enable='on';
                    app.EditField_15.Visible='on';
                    app.EditField_16.Visible='on';
                    app.EditField_15.Enable='on';
                    app.EditField_16.Enable='on';
                    app.EditField_17.Visible='on';
                    app.EditField_17.Enable='on';
                    app.EditField_18.Visible='on';
                    app.EditField_18.Enable='on';
                    app.EditField_19.Visible='on';
                    app.EditField_19.Enable='on';
                    app.EditField_20.Visible='on';
                    app.EditField_20.Enable='on';
                    app.TotalTimeLabel_2.Visible='on';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.CalculateButton.Text='Calculating';
            app.CalculateButton.Enable='off';
            switch (app.ChoosethetrajectorytechniqueButtonGroup.SelectedObject.Text)
                case 'Joint Space'
                    t1s=app.EditField_6.Value;
                    t2s=app.EditField_7.Value;
                    t3s=app.EditField_8.Value;
                    t4s=app.EditField_9.Value;
                    t5s=app.EditField_10.Value;
                    t1g=app.EditField.Value;
                    t2g=app.EditField_2.Value;
                    t3g=app.EditField_3.Value;
                    t4g=app.EditField_4.Value;
                    t5g=app.EditField_5.Value;
                    tg=app.EditField_11.Value;
                    ti=0:0.1:tg;
                    t1p=zeros(1,length(ti));
                    t2p=zeros(1,length(ti));
                    t3p=zeros(1,length(ti));
                    t4p=zeros(1,length(ti));
                    t5p=zeros(1,length(ti));
                    t1v=zeros(1,length(ti));
                    t2v=zeros(1,length(ti));
                    t3v=zeros(1,length(ti));
                    t4v=zeros(1,length(ti));
                    t5v=zeros(1,length(ti));
                    t1a=zeros(1,length(ti));
                    t2a=zeros(1,length(ti));
                    t3a=zeros(1,length(ti));
                    t4a=zeros(1,length(ti));
                    t5a=zeros(1,length(ti));
                   p1px=zeros(1,length(ti));
                   p2px=zeros(1,length(ti));
                   p3px=zeros(1,length(ti));
                   p4px=zeros(1,length(ti));
                   p5px=zeros(1,length(ti));
                   p1vx=zeros(1,length(ti));
                   p2vx=zeros(1,length(ti));
                   p3vx=zeros(1,length(ti));
                   p4vx=zeros(1,length(ti));
                   p5vx=zeros(1,length(ti));
                   p1wx=zeros(1,length(ti));
                   p2wx=zeros(1,length(ti));
                   p3wx=zeros(1,length(ti));
                   p4wx=zeros(1,length(ti));
                   p5wx=zeros(1,length(ti));
                   p1ax=zeros(1,length(ti));
                   p2ax=zeros(1,length(ti));
                   p3ax=zeros(1,length(ti));
                   p4ax=zeros(1,length(ti));
                   p5ax=zeros(1,length(ti));
                   p1lx=zeros(1,length(ti));
                   p2lx=zeros(1,length(ti));
                   p3lx=zeros(1,length(ti));
                   p4lx=zeros(1,length(ti));
                   p5lx=zeros(1,length(ti));
                   p1py=zeros(1,length(ti));
                   p2py=zeros(1,length(ti));
                   p3py=zeros(1,length(ti));
                   p4py=zeros(1,length(ti));
                   p5py=zeros(1,length(ti));
                   p1vy=zeros(1,length(ti));
                   p2vy=zeros(1,length(ti));
                   p3vy=zeros(1,length(ti));
                   p4vy=zeros(1,length(ti));
                   p5vy=zeros(1,length(ti));      
                   p1wy=zeros(1,length(ti));
                   p2wy=zeros(1,length(ti));
                   p3wy=zeros(1,length(ti));
                   p4wy=zeros(1,length(ti));
                   p5wy=zeros(1,length(ti));
                   p1ay=zeros(1,length(ti));
                   p2ay=zeros(1,length(ti));
                   p3ay=zeros(1,length(ti));
                   p4ay=zeros(1,length(ti));
                   p5ay=zeros(1,length(ti));
                   p1ly=zeros(1,length(ti));
                   p2ly=zeros(1,length(ti));
                   p3ly=zeros(1,length(ti));
                   p4ly=zeros(1,length(ti));
                   p5ly=zeros(1,length(ti));
                   p1pz=zeros(1,length(ti));
                   p2pz=zeros(1,length(ti));
                   p3pz=zeros(1,length(ti));
                   p4pz=zeros(1,length(ti));
                   p5pz=zeros(1,length(ti));
                   p1vz=zeros(1,length(ti));
                   p2vz=zeros(1,length(ti));
                   p3vz=zeros(1,length(ti));
                   p4vz=zeros(1,length(ti));
                   p5vz=zeros(1,length(ti));                   
                   p1wz=zeros(1,length(ti));
                   p2wz=zeros(1,length(ti));
                   p3wz=zeros(1,length(ti));
                   p4wz=zeros(1,length(ti));
                   p5wz=zeros(1,length(ti));
                   p1az=zeros(1,length(ti));
                   p2az=zeros(1,length(ti));
                   p3az=zeros(1,length(ti));
                   p4az=zeros(1,length(ti));
                   p5az=zeros(1,length(ti));
                   p1lz=zeros(1,length(ti));
                   p2lz=zeros(1,length(ti));
                   p3lz=zeros(1,length(ti));
                   p4lz=zeros(1,length(ti));
                   p5lz=zeros(1,length(ti));
                    switch (app.ButtonGroup.SelectedObject.Text)
                        case 'Cubic'
                            for i=1:length(ti)
                                a1=(3/tg^2)*(t1g-t1s);
                                b1=(3/tg^2)*(t2g-t2s);
                                c1=(3/tg^2)*(t3g-t3s);
                                d1=(3/tg^2)*(t4g-t4s);
                                e1=(3/tg^2)*(t5g-t5s);
                                a2=-(2/tg^3)*(t1g-t1s);
                                b2=-(2/tg^3)*(t2g-t2s);
                                c2=-(2/tg^3)*(t3g-t3s);
                                d2=-(2/tg^3)*(t4g-t4s);
                                e2=-(2/tg^3)*(t5g-t5s);
                                t1p(i)=t1s+ a1*ti(i)^2 + a2*ti(i)^3;
                                t2p(i)=t2s+ b1*ti(i)^2 + b2*ti(i)^3;
                                t3p(i)=t3s+ c1*ti(i)^2 + c2*ti(i)^3;
                                t4p(i)=t4s+ d1*ti(i)^2 + d2*ti(i)^3;
                                t5p(i)=t5s+ e1*ti(i)^2 + e2*ti(i)^3;
                                t1v(i)=2*a1*ti(i) + 3*a2*ti(i)^2;
                                t2v(i)=2*b1*ti(i) + 3*b2*ti(i)^2;
                                t3v(i)=2*c1*ti(i) + 3*c2*ti(i)^2;
                                t4v(i)=2*d1*ti(i) + 3*d2*ti(i)^2;
                                t5v(i)=2*e1*ti(i) + 3*e2*ti(i)^2;
                                t1a(i)=2*a1 + 6*a2*ti(i);
                                t2a(i)=2*b1 + 6*b2*ti(i);
                                t3a(i)=2*c1 + 6*c2*ti(i);
                                t4a(i)=2*d1 + 6*d2*ti(i);
                                t5a(i)=2*e1 + 6*e2*ti(i);
                                a=forward2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]);
                                bv=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1v(i)*pi/180;
                                cv=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i)]*pi/180;
                                dv=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ev=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fv=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                ba=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1a(i)*pi/180 + trans12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*t1v(i)*pi/180;
                                ca=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i)]*pi/180 + jacobian22([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i)]*pi/180;
                                da=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i)]*pi/180 + jacobian32([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ea=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i)]*pi/180 + jacobian42([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fa=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i);t5a(i)]*pi/180 + jacobian12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                p1px(i)=a(1,1);
                                p1py(i)=a(1,2);
                                p1pz(i)=a(1,3);
                                p2px(i)=a(2,1);
                                p2py(i)=a(2,2);
                                p2pz(i)=a(2,3);
                                p3px(i)=a(3,1);
                                p3py(i)=a(3,2);
                                p3pz(i)=a(3,3);
                                p4px(i)=a(4,1);
                                p4py(i)=a(4,2);
                                p4pz(i)=a(4,3);
                                p5px(i)=a(5,1);
                                p5py(i)=a(5,2);
                                p5pz(i)=a(5,3);
                                p1vx(i)=bv(1,1);
                                p1vy(i)=bv(2,1);
                                p1vz(i)=bv(3,1);
                                p1wx(i)=bv(4,1);
                                p1wy(i)=bv(5,1);
                                p1wz(i)=bv(6,1);
                                p1ax(i)=ba(1,1);
                                p1ay(i)=ba(2,1);
                                p1az(i)=ba(3,1);
                                p1lx(i)=ba(4,1);
                                p1ly(i)=ba(5,1);
                                p1lz(i)=ba(6,1);                                
                                p2vx(i)=cv(1,1);
                                p2vy(i)=cv(2,1);
                                p2vz(i)=cv(3,1);
                                p2wx(i)=cv(4,1);
                                p2wy(i)=cv(5,1);
                                p2wz(i)=cv(6,1);
                                p2ax(i)=ca(1,1);
                                p2ay(i)=ca(2,1);
                                p2az(i)=ca(3,1);
                                p2lx(i)=ca(4,1);
                                p2ly(i)=ca(5,1);
                                p2lz(i)=ca(6,1);                                
                                p3vx(i)=dv(1,1);
                                p3vy(i)=dv(2,1);
                                p3vz(i)=dv(3,1);
                                p3wx(i)=dv(4,1);
                                p3wy(i)=dv(5,1);
                                p3wz(i)=dv(6,1);
                                p3ax(i)=da(1,1);
                                p3ay(i)=da(2,1);
                                p3az(i)=da(3,1);
                                p3lx(i)=da(4,1);
                                p3ly(i)=da(5,1);
                                p3lz(i)=da(6,1);                                
                                p4vx(i)=ev(1,1);
                                p4vy(i)=ev(2,1);
                                p4vz(i)=ev(3,1);
                                p4wx(i)=ev(4,1);
                                p4wy(i)=ev(5,1);
                                p4wz(i)=ev(6,1);
                                p4ax(i)=ea(1,1);
                                p4ay(i)=ea(2,1);
                                p4az(i)=ea(3,1);
                                p4lx(i)=ea(4,1);
                                p4ly(i)=ea(5,1);
                                p4lz(i)=ea(6,1);                                
                                p5vx(i)=fv(1,1);
                                p5vy(i)=fv(2,1);
                                p5vz(i)=fv(3,1);
                                p5wx(i)=fv(4,1);
                                p5wy(i)=fv(5,1);
                                p5wz(i)=fv(6,1);
                                p5ax(i)=fa(1,1);
                                p5ay(i)=fa(2,1);
                                p5az(i)=fa(3,1);
                                p5lx(i)=fa(4,1);
                                p5ly(i)=fa(5,1);
                                p5lz(i)=fa(6,1);
                            end
                        case 'Cycloidal'
                            for i=1:length(ti)
                                t1p(i) = t1s + ((t1g-t1s)/tg)*(ti(i)-((tg/(2*pi))*sin(2*pi*ti(i)/tg)));
                                t2p(i) = t2s + ((t2g-t2s)/tg)*(ti(i)-((tg/(2*pi))*sin(2*pi*ti(i)/tg)));
                                t3p(i) = t3s + ((t3g-t3s)/tg)*(ti(i)-((tg/(2*pi))*sin(2*pi*ti(i)/tg)));
                                t4p(i) = t4s + ((t4g-t4s)/tg)*(ti(i)-((tg/(2*pi))*sin(2*pi*ti(i)/tg)));
                                t5p(i) = t5s + ((t5g-t5s)/tg)*(ti(i)-((tg/(2*pi))*sin(2*pi*ti(i)/tg)));
                                t1v(i) = ((t1s-t1g)/tg)*cos((2*pi*ti(i))/tg);
                                t2v(i) = ((t2s-t2g)/tg)*cos((2*pi*ti(i))/tg);
                                t3v(i) = ((t3s-t3g)/tg)*cos((2*pi*ti(i))/tg);
                                t4v(i) = ((t4s-t4g)/tg)*cos((2*pi*ti(i))/tg);
                                t5v(i) = ((t5s-t5g)/tg)*cos((2*pi*ti(i))/tg);
                                t1a(i) = (2*pi/tg)*((t1s-t1g)/tg)*sin((2*pi*ti(i))/tg);
                                t2a(i) = (2*pi/tg)*((t2s-t2g)/tg)*sin((2*pi*ti(i))/tg);
                                t3a(i) = (2*pi/tg)*((t3s-t3g)/tg)*sin((2*pi*ti(i))/tg);
                                t4a(i) = (2*pi/tg)*((t4s-t4g)/tg)*sin((2*pi*ti(i))/tg);
                                t5a(i) = (2*pi/tg)*((t5s-t5g)/tg)*sin((2*pi*ti(i))/tg);
                                a=forward2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]);
                                bv=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1v(i)*pi/180;
                                cv=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i)]*pi/180;
                                dv=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ev=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fv=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                ba=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1a(i)*pi/180 + trans12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*t1v(i)*pi/180;
                                ca=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i)]*pi/180 + jacobian22([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i)]*pi/180;
                                da=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i)]*pi/180 + jacobian32([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ea=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i)]*pi/180 + jacobian42([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fa=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i);t5a(i)]*pi/180 + jacobian12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                p1px(i)=a(1,1);
                                p1py(i)=a(1,2);
                                p1pz(i)=a(1,3);
                                p2px(i)=a(2,1);
                                p2py(i)=a(2,2);
                                p2pz(i)=a(2,3);
                                p3px(i)=a(3,1);
                                p3py(i)=a(3,2);
                                p3pz(i)=a(3,3);
                                p4px(i)=a(4,1);
                                p4py(i)=a(4,2);
                                p4pz(i)=a(4,3);
                                p5px(i)=a(5,1);
                                p5py(i)=a(5,2);
                                p5pz(i)=a(5,3);
                                p1vx(i)=bv(1,1);
                                p1vy(i)=bv(2,1);
                                p1vz(i)=bv(3,1);
                                p1wx(i)=bv(4,1);
                                p1wy(i)=bv(5,1);
                                p1wz(i)=bv(6,1);
                                p1ax(i)=ba(1,1);
                                p1ay(i)=ba(2,1);
                                p1az(i)=ba(3,1);
                                p1lx(i)=ba(4,1);
                                p1ly(i)=ba(5,1);
                                p1lz(i)=ba(6,1);                                
                                p2vx(i)=cv(1,1);
                                p2vy(i)=cv(2,1);
                                p2vz(i)=cv(3,1);
                                p2wx(i)=cv(4,1);
                                p2wy(i)=cv(5,1);
                                p2wz(i)=cv(6,1);
                                p2ax(i)=ca(1,1);
                                p2ay(i)=ca(2,1);
                                p2az(i)=ca(3,1);
                                p2lx(i)=ca(4,1);
                                p2ly(i)=ca(5,1);
                                p2lz(i)=ca(6,1);                                
                                p3vx(i)=dv(1,1);
                                p3vy(i)=dv(2,1);
                                p3vz(i)=dv(3,1);
                                p3wx(i)=dv(4,1);
                                p3wy(i)=dv(5,1);
                                p3wz(i)=dv(6,1);
                                p3ax(i)=da(1,1);
                                p3ay(i)=da(2,1);
                                p3az(i)=da(3,1);
                                p3lx(i)=da(4,1);
                                p3ly(i)=da(5,1);
                                p3lz(i)=da(6,1);                                
                                p4vx(i)=ev(1,1);
                                p4vy(i)=ev(2,1);
                                p4vz(i)=ev(3,1);
                                p4wx(i)=ev(4,1);
                                p4wy(i)=ev(5,1);
                                p4wz(i)=ev(6,1);
                                p4ax(i)=ea(1,1);
                                p4ay(i)=ea(2,1);
                                p4az(i)=ea(3,1);
                                p4lx(i)=ea(4,1);
                                p4ly(i)=ea(5,1);
                                p4lz(i)=ea(6,1);                                
                                p5vx(i)=fv(1,1);
                                p5vy(i)=fv(2,1);
                                p5vz(i)=fv(3,1);
                                p5wx(i)=fv(4,1);
                                p5wy(i)=fv(5,1);
                                p5wz(i)=fv(6,1);
                                p5ax(i)=fa(1,1);
                                p5ay(i)=fa(2,1);
                                p5az(i)=fa(3,1);
                                p5lx(i)=fa(4,1);
                                p5ly(i)=fa(5,1);
                                p5lz(i)=fa(6,1);
                            end
                        case 'Parabolic'
                                q1c = 4*(ceil((t1g-t1s)/tg^2));
                                q2c = 4*(ceil((t2g-t2s)/tg^2));
                                q3c = 4*(ceil((t3g-t3s)/tg^2));
                                q4c = 4*(ceil((t4g-t4s)/tg^2));
                                q5c = 4*(ceil((t5g-t5s)/tg^2));
                                
                                t1b = tg/2 - 1/2*(sqrt((tg^2*q1c-4*(t1g-t1s))/q1c));
                                t2b = tg/2 - 1/2*(sqrt((tg^2*q2c-4*(t2g-t2s))/q2c));
                                t3b = tg/2 - 1/2*(sqrt((tg^2*q3c-4*(t3g-t3s))/q3c));
                                t4b = tg/2 - 1/2*(sqrt((tg^2*q4c-4*(t4g-t4s))/q4c));
                                t5b = tg/2 - 1/2*(sqrt((tg^2*q5c-4*(t5g-t5s))/q5c));
                                for i=1:length(ti)
                                
                                    if (0<=ti(i)) && (ti(i)<=t1b)
                                        t1p(i) = t1s + 1/2*q1c*ti(i)^2;
                                    end
                                    if (t1b<=ti(i)) && (ti(i)<=(tg-t1b))
                                        t1p(i) = t1s - 1/2*q1c*t1b^2+q1c*t1b*ti(i);
                                    end
                                    if ((tg-t1b)<=ti(i)) && (ti(i)<=tg)
                                        t1p(i) = t1g - 1/2*q1c*(tg-ti(i))^2;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t2b)
                                        t2p(i) = t2s + 1/2*q2c*ti(i)^2;
                                    end
                                    if (t2b<=ti(i)) && (ti(i)<=(tg-t2b))
                                        t2p(i) = t2s - 1/2*q2c*t2b^2+q2c*t2b*ti(i);
                                    end
                                    if ((tg-t2b)<=ti(i)) && (ti(i)<=tg)
                                        t2p(i) = t2g - 1/2*q2c*(tg-ti(i))^2;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t3b)
                                        t3p(i) = t3s + 1/2*q3c*ti(i)^2;
                                    end
                                    if (t3b<=ti(i)) && (ti(i)<=(tg-t3b))
                                        t3p(i) = t3s - 1/2*q3c*t3b^2+q3c*t3b*ti(i);
                                    end
                                    if ((tg-t3b)<=ti(i)) && (ti(i)<=tg)
                                        t3p(i) = t3g - 1/2*q3c*(tg-ti(i))^2;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t4b)
                                        t4p(i) = t4s + 1/2*q4c*ti(i)^2;
                                    end
                                    if (t4b<=ti(i)) && (ti(i)<=(tg-t4b))
                                        t4p(i) = t4s - 1/2*q4c*t4b^2+q4c*t4b*ti(i);
                                    end
                                    if ((tg-t4b)<=ti(i)) && (ti(i)<=tg)
                                        t4p(i) = t4g - 1/2*q4c*(tg-ti(i))^2;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t5b)
                                        t5p(i) = t5s + 1/2*q5c*ti(i)^2;
                                    end
                                    if (t5b<=ti(i)) && (ti(i)<=(tg-t5b))
                                        t5p(i) = t5s - 1/2*q5c*t5b^2+q5c*t5b*ti(i);
                                    end
                                    if ((tg-t5b)<=ti(i)) && (ti(i)<=tg)
                                        t5p(i) = t5g - 1/2*q5c*(tg-ti(i))^2;
                                    end
                                        
                                    if (0<=ti(i)) && (ti(i)<=t1b)
                                        t1v(i) = q1c*ti(i);
                                    end
                                    if (t1b<=ti(i)) && (ti(i)<=(tg-t1b))
                                        t1v(i) = q1c*t1b;
                                    end
                                    if ((tg-t1b)<=ti(i)) && (ti(i)<=tg)
                                        t1v(i) = q1c*(tg-ti(i));
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t2b)
                                        t2v(i) = q2c*ti(i);
                                    end
                                    if (t2b<=ti(i)) && (ti(i)<=(tg-t2b))
                                        t2v(i) = q2c*t2b;
                                    end
                                    if ((tg-t2b)<=ti(i)) && (ti(i)<=tg)
                                        t2v(i) = q2c*(tg-ti(i));
                                    end
                                 
                                    if (0<=ti(i)) && (ti(i)<=t3b)
                                        t3v(i) = q3c*ti(i);
                                    end
                                    if (t3b<=ti(i)) && (ti(i)<=(tg-t3b))
                                        t3v(i) = q3c*t3b;
                                    end
                                    if ((tg-t3b)<=ti(i)) && (ti(i)<=tg)
                                        t3v(i) = q3c*(tg-ti(i));
                                    end
                                
                                    if (0<=ti(i)) && (ti(i)<=t4b)
                                        t4v(i) = q4c*ti(i);
                                    end
                                    if (t4b<=ti(i)) && (ti(i)<=(tg-t4b))
                                        t4v(i) = q4c*t4b;
                                    end
                                    if ((tg-t4b)<=ti(i)) && (ti(i)<=tg)
                                        t4v(i) = q4c*(tg-ti(i));
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t5b)
                                        t5v(i) = q5c*ti(i);
                                    end
                                    if (t5b<=ti(i)) && (ti(i)<=(tg-t5b))
                                        t5v(i) = q5c*t5b;
                                    end
                                    if ((tg-t5b)<=ti(i)) && (ti(i)<=tg)
                                        t5v(i) = q5c*(tg-ti(i));
                                    end
                                
                                    if (0<=ti(i)) && (ti(i)<=t1b)
                                        t1a(i) = q1c;
                                    end
                                    if (t1b<=ti(i)) && (ti(i)<=(tg-t1b))
                                        t1a(i) = 0;
                                    end
                                    if ((tg-t1b)<=ti(i)) && (ti(i)<=tg)
                                        t1a(i) = -q1c;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t2b)
                                        t2a(i) = q2c;
                                    end
                                    if (t2b<=ti(i)) && (ti(i)<=(tg-t2b))
                                        t2a(i) = 0;
                                    end
                                    if ((tg-t2b)<=ti(i)) && (ti(i)<=tg)
                                        t2a(i) = -q2c;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t3b)
                                        t3a(i) = q3c;
                                    end
                                    if (t3b<=ti(i)) && (ti(i)<=(tg-t3b))
                                        t3a(i) = 0;
                                    end
                                    if ((tg-t3b)<=ti(i)) && (ti(i)<=tg)
                                        t3a(i) = -q3c;
                                    end
                                        
                                    if (0<=ti(i)) && (ti(i)<=t4b)
                                        t4a(i) = q4c;
                                    end
                                    if (t4b<=ti(i)) && (ti(i)<=(tg-t4b))
                                        t4a(i) = 0;
                                    end
                                    if ((tg-t4b)<=ti(i)) && (ti(i)<=tg)
                                        t4a(i) = -q4c;
                                    end
                                    
                                    if (0<=ti(i)) && (ti(i)<=t5b)
                                        t5a(i) = q5c;
                                    end
                                    if (t5b<=ti(i)) && (ti(i)<=(tg-t5b))
                                        t5a(i) = 0;
                                    end
                                    if ((tg-t5b)<=ti(i)) && (ti(i)<=tg)
                                        t5a(i) = -q5c;
                                    end
                                    a=forward2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]);
                                bv=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1v(i)*pi/180;
                                cv=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i)]*pi/180;
                                dv=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ev=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fv=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                ba=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1a(i)*pi/180 + trans12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*t1v(i)*pi/180;
                                ca=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i)]*pi/180 + jacobian22([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i)]*pi/180;
                                da=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i)]*pi/180 + jacobian32([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ea=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i)]*pi/180 + jacobian42([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fa=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i);t5a(i)]*pi/180 + jacobian12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                p1px(i)=a(1,1);
                                p1py(i)=a(1,2);
                                p1pz(i)=a(1,3);
                                p2px(i)=a(2,1);
                                p2py(i)=a(2,2);
                                p2pz(i)=a(2,3);
                                p3px(i)=a(3,1);
                                p3py(i)=a(3,2);
                                p3pz(i)=a(3,3);
                                p4px(i)=a(4,1);
                                p4py(i)=a(4,2);
                                p4pz(i)=a(4,3);
                                p5px(i)=a(5,1);
                                p5py(i)=a(5,2);
                                p5pz(i)=a(5,3);
                                p1vx(i)=bv(1,1);
                                p1vy(i)=bv(2,1);
                                p1vz(i)=bv(3,1);
                                p1wx(i)=bv(4,1);
                                p1wy(i)=bv(5,1);
                                p1wz(i)=bv(6,1);
                                p1ax(i)=ba(1,1);
                                p1ay(i)=ba(2,1);
                                p1az(i)=ba(3,1);
                                p1lx(i)=ba(4,1);
                                p1ly(i)=ba(5,1);
                                p1lz(i)=ba(6,1);                                
                                p2vx(i)=cv(1,1);
                                p2vy(i)=cv(2,1);
                                p2vz(i)=cv(3,1);
                                p2wx(i)=cv(4,1);
                                p2wy(i)=cv(5,1);
                                p2wz(i)=cv(6,1);
                                p2ax(i)=ca(1,1);
                                p2ay(i)=ca(2,1);
                                p2az(i)=ca(3,1);
                                p2lx(i)=ca(4,1);
                                p2ly(i)=ca(5,1);
                                p2lz(i)=ca(6,1);                                
                                p3vx(i)=dv(1,1);
                                p3vy(i)=dv(2,1);
                                p3vz(i)=dv(3,1);
                                p3wx(i)=dv(4,1);
                                p3wy(i)=dv(5,1);
                                p3wz(i)=dv(6,1);
                                p3ax(i)=da(1,1);
                                p3ay(i)=da(2,1);
                                p3az(i)=da(3,1);
                                p3lx(i)=da(4,1);
                                p3ly(i)=da(5,1);
                                p3lz(i)=da(6,1);                                
                                p4vx(i)=ev(1,1);
                                p4vy(i)=ev(2,1);
                                p4vz(i)=ev(3,1);
                                p4wx(i)=ev(4,1);
                                p4wy(i)=ev(5,1);
                                p4wz(i)=ev(6,1);
                                p4ax(i)=ea(1,1);
                                p4ay(i)=ea(2,1);
                                p4az(i)=ea(3,1);
                                p4lx(i)=ea(4,1);
                                p4ly(i)=ea(5,1);
                                p4lz(i)=ea(6,1);                                
                                p5vx(i)=fv(1,1);
                                p5vy(i)=fv(2,1);
                                p5vz(i)=fv(3,1);
                                p5wx(i)=fv(4,1);
                                p5wy(i)=fv(5,1);
                                p5wz(i)=fv(6,1);
                                p5ax(i)=fa(1,1);
                                p5ay(i)=fa(2,1);
                                p5az(i)=fa(3,1);
                                p5lx(i)=fa(4,1);
                                p5ly(i)=fa(5,1);
                                p5lz(i)=fa(6,1);
                                end 
                    end
                case 'Cartesian Space'
                    a=str2sym(app.EditField_13.Value);
                    b=str2sym(app.EditField_15.Value);
                    c=str2sym(app.EditField_14.Value);
                    d=str2sym(app.EditField_16.Value);
                    k=str2sym(app.EditField_17.Value);
                    l=str2sym(app.EditField_18.Value);
                    m=str2sym(app.EditField_19.Value);
                    n=str2sym(app.EditField_20.Value);
                    tg=app.EditField_12.Value;
                    syms u
                    s(symvar(d))=d;
                    r(symvar(n))=n;
                    if ~isempty(symvar(a))
                        pxs(symvar(d))=compose(a,s);
                        pxv=diff(pxs);
                        pxa=diff(pxs,2);
                    else
                    pxs(u)=a;
                    pxv(u)=diff(a,u);
                    pxa(u)=diff(a,u,2);
                    end
                    if ~isempty(symvar(b))
                    pys(symvar(d))=compose(b,s);
                        pyv=diff(pys);
                        pya=diff(pys,2);
                    else
                    pys(u)=b;
                    pyv(u)=diff(b,u);
                    pya(u)=diff(b,u,2);
                    end
                    if ~isempty(symvar(c))
                    pzs(symvar(d))=compose(c,s);
                        pzv=diff(pzs);
                        pza=diff(pzs,2);
                    else
                    pzs(u)=c;
                    pzv(u)=diff(c,u);
                    pza(u)=diff(c,u,2);
                    end
                    if ~isempty(symvar(l))
                    ppo(symvar(n))=compose(l,r);
                        ppw=diff(ppo);
                        ppl=diff(ppo,2);
                    else
                    ppo(u)=l;
                    ppw(u)=diff(l,u);
                    ppl(u)=diff(l,u,2);
                    end
                    if ~isempty(symvar(k))
                    pro(symvar(n))=compose(k,r);
                    prw=diff(pro);
                    prl=diff(pro,2);
                    else
                    pro(u)=k;
                    prw(u)=diff(k,u);
                    prl(u)=diff(k,u,2);
                    end
                    if ~isempty(symvar(m))
                    pyo(symvar(n))=compose(m,r);
                    pyw=diff(pyo);
                    pyl=diff(pyo,2);
                    else
                    pyo(u)=m;
                    pyw(u)=diff(m,u);
                    pyl(u)=diff(m,u,2);
                    end
                    disp(pxs)
                    disp(pys)
                    disp(pzs)
                    disp(pxv)
                    disp(pyv)
                    disp(pzv)
                    disp(pxa)
                    disp(pya)
                    disp(pza)
                    disp(pro)
                    disp(ppo)
                    disp(pyo)
                    disp(prw)
                    disp(ppw)
                    disp(pyw)
                    disp(prl)
                    disp(ppl)
                    disp(pyl)
                    ti=0:0.1:tg;
                    t1p=zeros(1,length(ti));
                    t2p=zeros(1,length(ti));
                    t3p=zeros(1,length(ti));
                    t4p=zeros(1,length(ti));
                    t5p=zeros(1,length(ti));
                    t1v=zeros(1,length(ti));
                    t2v=zeros(1,length(ti));
                    t3v=zeros(1,length(ti));
                    t4v=zeros(1,length(ti));
                    t5v=zeros(1,length(ti));
                    t1a=zeros(1,length(ti));
                    t2a=zeros(1,length(ti));
                    t3a=zeros(1,length(ti));
                    t4a=zeros(1,length(ti));
                    t5a=zeros(1,length(ti));
                   p1px=zeros(1,length(ti));
                   p2px=zeros(1,length(ti));
                   p3px=zeros(1,length(ti));
                   p4px=zeros(1,length(ti));
                   p1vx=zeros(1,length(ti));
                   p2vx=zeros(1,length(ti));
                   p3vx=zeros(1,length(ti));
                   p4vx=zeros(1,length(ti));
                   p1wx=zeros(1,length(ti));
                   p2wx=zeros(1,length(ti));
                   p3wx=zeros(1,length(ti));
                   p4wx=zeros(1,length(ti));
                   p1ax=zeros(1,length(ti));
                   p2ax=zeros(1,length(ti));
                   p3ax=zeros(1,length(ti));
                   p4ax=zeros(1,length(ti));
                   p1lx=zeros(1,length(ti));
                   p2lx=zeros(1,length(ti));
                   p3lx=zeros(1,length(ti));
                   p4lx=zeros(1,length(ti));
                   p1py=zeros(1,length(ti));
                   p2py=zeros(1,length(ti));
                   p3py=zeros(1,length(ti));
                   p4py=zeros(1,length(ti));
                   p1vy=zeros(1,length(ti));
                   p2vy=zeros(1,length(ti));
                   p3vy=zeros(1,length(ti));
                   p4vy=zeros(1,length(ti));     
                   p1wy=zeros(1,length(ti));
                   p2wy=zeros(1,length(ti));
                   p3wy=zeros(1,length(ti));
                   p4wy=zeros(1,length(ti));
                   p1ay=zeros(1,length(ti));
                   p2ay=zeros(1,length(ti));
                   p3ay=zeros(1,length(ti));
                   p4ay=zeros(1,length(ti));
                   p1ly=zeros(1,length(ti));
                   p2ly=zeros(1,length(ti));
                   p3ly=zeros(1,length(ti));
                   p4ly=zeros(1,length(ti));
                   p1pz=zeros(1,length(ti));
                   p2pz=zeros(1,length(ti));
                   p3pz=zeros(1,length(ti));
                   p4pz=zeros(1,length(ti));
                   p1vz=zeros(1,length(ti));
                   p2vz=zeros(1,length(ti));
                   p3vz=zeros(1,length(ti));
                   p4vz=zeros(1,length(ti));                   
                   p1wz=zeros(1,length(ti));
                   p2wz=zeros(1,length(ti));
                   p3wz=zeros(1,length(ti));
                   p4wz=zeros(1,length(ti));
                   p1az=zeros(1,length(ti));
                   p2az=zeros(1,length(ti));
                   p3az=zeros(1,length(ti));
                   p4az=zeros(1,length(ti));
                   p1lz=zeros(1,length(ti));
                   p2lz=zeros(1,length(ti));
                   p3lz=zeros(1,length(ti));
                   p4lz=zeros(1,length(ti));
                    p5px=zeros(1,length(ti));
                    p5py=zeros(1,length(ti));
                    p5pz=zeros(1,length(ti));
                    p5vx=zeros(1,length(ti));
                    p5vy=zeros(1,length(ti));
                    p5vz=zeros(1,length(ti));
                    p5ax=zeros(1,length(ti));
                    p5ay=zeros(1,length(ti));
                    p5az=zeros(1,length(ti));
                    p5ro=zeros(1,length(ti));
                    p5yo=zeros(1,length(ti));
                    p5po=zeros(1,length(ti));
                    p5wx=zeros(1,length(ti));
                    p5wy=zeros(1,length(ti));
                    p5wz=zeros(1,length(ti));
                    p5lx=zeros(1,length(ti));
                    p5ly=zeros(1,length(ti));
                    p5lz=zeros(1,length(ti));
                    for i=1:length(ti)
                        e=[pxs(ti(i));pys(ti(i));pzs(ti(i))];
                        f=[pxv(ti(i));pyv(ti(i));pzv(ti(i))];
                        g=[pxa(ti(i));pya(ti(i));pza(ti(i))];
                        u=[pro(ti(i))*pi/180;ppo(ti(i))*pi/180;pyo(ti(i))*pi/180];
                        v=[prw(ti(i))*pi/180;ppw(ti(i))*pi/180;pyw(ti(i))*pi/180];
                        w=[prl(ti(i))*pi/180;ppl(ti(i))*pi/180;pyl(ti(i))*pi/180];
                        p5px(i)=e(1,1);
                        p5py(i)=e(2,1);
                        p5pz(i)=e(3,1);
                        p5vx(i)=f(1,1);
                        p5vy(i)=f(2,1);
                        p5vz(i)=f(3,1);
                        p5ax(i)=g(1,1);
                        p5ay(i)=g(2,1);
                        p5az(i)=g(3,1);
                        p5ro(i)=u(1,1);
                        p5po(i)=u(2,1);
                        p5yo(i)=u(3,1);
                        p5wx(i)=v(1,1);
                        p5wy(i)=v(2,1);
                        p5wz(i)=v(3,1);
                        p5lx(i)=w(1,1);
                        p5ly(i)=w(2,1);
                        p5lz(i)=w(3,1);                        
                    end
                    for i=1:length(ti)
                        aa=rotz(p5yo(i))*roty(p5po(i))*rotx(p5ro(i));
                        bb=[p5px(i);p5py(i);p5pz(i)];
                        cc=[aa,bb;0 0 0 1];
                        dd=inverse5([bb' p5po(i)*180/pi]);
                        t1p(i)=dd(1);
                        t2p(i)=dd(2);
                        t3p(i)=dd(3);
                        t4p(i)=dd(4);
                        t5p(i)=dd(5);
                        ee=pinv(jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]))*[p5vx(i);p5vy(i);p5vz(i);p5wx(i);p5wy(i);p5wz(i)];                        
                        t1v(i)=ee(1,1)*180/pi;
                        t2v(i)=ee(2,1)*180/pi;
                        t3v(i)=ee(3,1)*180/pi;
                        t4v(i)=ee(4,1)*180/pi;
                        t5v(i)=ee(5,1)*180/pi;
                        ff=pinv(jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]))*([p5ax(i);p5ay(i);p5az(i);p5lx(i);p5ly(i);p5lz(i)] - jacobian12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180);
                        t1a(i)=ff(1,1)*180/pi;
                        t2a(i)=ff(2,1)*180/pi;
                        t3a(i)=ff(3,1)*180/pi;
                        t4a(i)=ff(4,1)*180/pi;
                        t5a(i)=ff(5,1)*180/pi;                       
                             a=forward2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)]);
                                bv=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1v(i)*pi/180;
                                cv=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i)]*pi/180;
                                dv=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ev=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fv=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                ba=trans1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*t1a(i)*pi/180 + trans12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*t1v(i)*pi/180;
                                ca=jacobian2([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i)]*pi/180 + jacobian22([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i)]*pi/180;
                                da=jacobian3([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i)]*pi/180 + jacobian32([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i)]*pi/180;
                                ea=jacobian4([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i)]*pi/180 + jacobian42([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i)]*pi/180;
                                fa=jacobian1([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i)])*[t1a(i);t2a(i);t3a(i);t4a(i);t5a(i)]*pi/180 + jacobian12([t1p(i) t2p(i) t3p(i) t4p(i) t5p(i) t1v(i) t2v(i) t3v(i) t4v(i) t5v(i)])*[t1v(i);t2v(i);t3v(i);t4v(i);t5v(i)]*pi/180;
                                p1px(i)=a(1,1);
                                p1py(i)=a(1,2);
                                p1pz(i)=a(1,3);
                                p2px(i)=a(2,1);
                                p2py(i)=a(2,2);
                                p2pz(i)=a(2,3);
                                p3px(i)=a(3,1);
                                p3py(i)=a(3,2);
                                p3pz(i)=a(3,3);
                                p4px(i)=a(4,1);
                                p4py(i)=a(4,2);
                                p4pz(i)=a(4,3);
                                p5px(i)=a(5,1);
                                p5py(i)=a(5,2);
                                p5pz(i)=a(5,3);
                                p1vx(i)=bv(1,1);
                                p1vy(i)=bv(2,1);
                                p1vz(i)=bv(3,1);
                                p1wx(i)=bv(4,1);
                                p1wy(i)=bv(5,1);
                                p1wz(i)=bv(6,1);
                                p1ax(i)=ba(1,1);
                                p1ay(i)=ba(2,1);
                                p1az(i)=ba(3,1);
                                p1lx(i)=ba(4,1);
                                p1ly(i)=ba(5,1);
                                p1lz(i)=ba(6,1);                                
                                p2vx(i)=cv(1,1);
                                p2vy(i)=cv(2,1);
                                p2vz(i)=cv(3,1);
                                p2wx(i)=cv(4,1);
                                p2wy(i)=cv(5,1);
                                p2wz(i)=cv(6,1);
                                p2ax(i)=ca(1,1);
                                p2ay(i)=ca(2,1);
                                p2az(i)=ca(3,1);
                                p2lx(i)=ca(4,1);
                                p2ly(i)=ca(5,1);
                                p2lz(i)=ca(6,1);                                
                                p3vx(i)=dv(1,1);
                                p3vy(i)=dv(2,1);
                                p3vz(i)=dv(3,1);
                                p3wx(i)=dv(4,1);
                                p3wy(i)=dv(5,1);
                                p3wz(i)=dv(6,1);
                                p3ax(i)=da(1,1);
                                p3ay(i)=da(2,1);
                                p3az(i)=da(3,1);
                                p3lx(i)=da(4,1);
                                p3ly(i)=da(5,1);
                                p3lz(i)=da(6,1);                                
                                p4vx(i)=ev(1,1);
                                p4vy(i)=ev(2,1);
                                p4vz(i)=ev(3,1);
                                p4wx(i)=ev(4,1);
                                p4wy(i)=ev(5,1);
                                p4wz(i)=ev(6,1);
                                p4ax(i)=ea(1,1);
                                p4ay(i)=ea(2,1);
                                p4az(i)=ea(3,1);
                                p4lx(i)=ea(4,1);
                                p4ly(i)=ea(5,1);
                                p4lz(i)=ea(6,1);                                
                                p5vx(i)=fv(1,1);
                                p5vy(i)=fv(2,1);
                                p5vz(i)=fv(3,1);
                                p5wx(i)=fv(4,1);
                                p5wy(i)=fv(5,1);
                                p5wz(i)=fv(6,1);
                                p5ax(i)=fa(1,1);
                                p5ay(i)=fa(2,1);
                                p5az(i)=fa(3,1);
                                p5lx(i)=fa(4,1);
                                p5ly(i)=fa(5,1);
                                p5lz(i)=fa(6,1);
                    end
            end
            app.pj=[t1p;t2p;t3p;t4p;t5p];
            app.vj=[t1v;t2v;t3v;t4v;t5v];
            app.aj=[t1a;t2a;t3a;t4a;t5a];
            app.px=[p1px;p2px;p3px;p4px;p5px];
            app.py=[p1py;p2py;p3py;p4py;p5py];
            app.pz=[p1pz;p2pz;p3pz;p4pz;p5pz];
            app.vx=[p1vx;p2vx;p3vx;p4vx;p5vx];
            app.vy=[p1vy;p2vy;p3vy;p4vy;p5vy];
            app.vz=[p1vz;p2vz;p3vz;p4vz;p5vz];
            app.ax=[p1ax;p2ax;p3ax;p4ax;p5ax];
            app.ay=[p1ay;p2ay;p3ay;p4ay;p5ay];
            app.az=[p1az;p2az;p3az;p4az;p5az];
            app.wx=[p1wx;p2wx;p3wx;p4wx;p5wx];
            app.wy=[p1wy;p2wy;p3wy;p4wy;p5wy];
            app.wz=[p1wz;p2wz;p3wz;p4wz;p5wz];
            app.lx=[p1lx;p2lx;p3lx;p4lx;p5lx];
            app.ly=[p1ly;p2ly;p3ly;p4ly;p5ly];
            app.lz=[p1lz;p2lz;p3lz;p4lz;p5lz];
            app.ts=ti;
            a=p5px;
            b=p5py;
            c=p5pz;
            d=p5vx;
            e=p5vy;
            f=p5vz;
            g=p5ax;
            h=p5ay;
            i=p5az;
            download(a,b,c,d,e,f,g,h,i)
            app.CalculateButton.Text='Calculate';
            app.CalculateButton.Enable='on';
            app.ShowgraphsButton.Enable='on';
            app.SimulateButton.Enable='on';
            download2(app.pj)
            download3(app.pj,app.vj,app.aj)
            size(app.pj)
        end

        % Selection changed function: ChooseButtonGroup
        function ChooseButtonGroupSelectionChanged(app, event)
            s = app.ChooseButtonGroup.SelectedObject.Text;
            switch s
                case 'Link'
                    app.AngularVelocityButton.Visible='on';
                    app.AngularAccelerationButton.Visible='on';
                    app.AxisButtonGroup.Visible='on';
                    app.AxisButtonGroup.Enable='on';
                    app.XButton.Visible='on';
                    app.XButton.Enable='on';
                    app.YButton.Visible='on';
                    app.YButton.Enable='on';
                    app.ZButton.Visible='on';
                    app.ZButton.Enable='on';
                    app.AngularVelocityButton.Enable='on';
                    app.AngularAccelerationButton.Enable='on';     
                    app.Joint1Node.Text='Link1';
                    app.Joint2Node.Text='Link2';
                    app.Joint3Node.Text='Link3';
                    app.Joint4Node.Text='Link4';
                    app.Joint5Node.Text='Link5';
                case 'Joint'
                    app.AngularVelocityButton.Visible='off';
                    app.AngularAccelerationButton.Visible='off';
                    app.AngularVelocityButton.Enable='off';
                    app.AngularAccelerationButton.Enable='off';
                    app.AxisButtonGroup.Visible='off';
                    app.AxisButtonGroup.Enable='off';
                    app.XButton.Visible='off';
                    app.XButton.Enable='off';
                    app.YButton.Visible='off';
                    app.YButton.Enable='off';
                    app.ZButton.Visible='off';
                    app.ZButton.Enable='off';
                    app.Joint1Node.Text='Joint1';
                    app.Joint2Node.Text='Joint2';
                    app.Joint3Node.Text='Joint3';
                    app.Joint4Node.Text='Joint4';
                    app.Joint5Node.Text='Joint5';
            end
            
        end

        % Button pushed function: ShowgraphsButton
        function ShowgraphsButtonPushed(app, event)
            s = app.ChooseButtonGroup.SelectedObject.Text;
            close(figure(1))
            a=[];
            g={};
            d={};
            switch s
                case 'Joint'
                    a=app.Tree.CheckedNodes;
                    p=zeros(length(a),length(app.ts));
                    b=app.ButtonGroup_2.SelectedObject.Text;
                    switch b
                        case 'Position'
                            for i=1:length(a)
                                t=a(i).Text;
                                g(i)={a(i).Text};
                                if t=='Joint1'
                                    p(i,:)=app.pj(1,:);
                                end
                                if t=='Joint2'
                                    p(i,:)=app.pj(2,:);
                                end
                                if t=='Joint3'
                                    p(i,:)=app.pj(3,:);
                                end
                                if t=='Joint4'
                                    p(i,:)=app.pj(4,:);
                                end
                                if t=='Joint5'
                                    p(i,:)=app.pj(5,:);
                                end                    
                            end
                            d=append(b,{' (deg)'});
                        case 'Velocity'
                            for i=1:length(a)
                                t=a(i).Text;
                                g(i)={a(i).Text};                                
                                if t=='Joint1'
                                    p(i,:)=app.vj(1,:);
                                end
                                if t=='Joint2'
                                    p(i,:)=app.vj(2,:);
                                end
                                if t=='Joint3'
                                    p(i,:)=app.vj(3,:);
                                end
                                if t=='Joint4'
                                    p(i,:)=app.vj(4,:);
                                end
                                if t=='Joint5'
                                    p(i,:)=app.vj(5,:);
                                end
                            end
                            d=append(b,{' (deg/sec)'});
                        case 'Acceleration'
                            for i=1:length(a)
                                t=a(i).Text;
                                g(i)={a(i).Text};
                                if t=='Joint1'
                                    p(i,:)=app.aj(1,:);
                                end
                                if t=='Joint2'
                                    p(i,:)=app.aj(2,:);
                                end
                                if t=='Joint3'
                                    p(i,:)=app.aj(3,:);
                                end
                                if t=='Joint4'
                                    p(i,:)=app.aj(4,:);
                                end
                                if t=='Joint5'
                                    p(i,:)=app.aj(5,:);
                                end
                            end
                            d=append(b,{' (deg/sec^2)'});
                            
                    end
                    case 'Link'                        
                        a=app.Tree.CheckedNodes;
                        p=zeros(length(a),length(app.ts));
                        b=app.ButtonGroup_2.SelectedObject.Text;
                        switch b
                            case 'Position'
                                d=append(b,{' (mm)'});
                                c=app.AxisButtonGroup.SelectedObject.Text;
                                switch c
                                    case 'X'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.px(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.px(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.px(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.px(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.px(5,:);
                                            end                                
                                        end
                                    case 'Y'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.py(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.py(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.py(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.py(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.py(5,:);
                                            end                                
                                        end
                                    case 'Z'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.pz(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.pz(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.pz(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.pz(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.pz(5,:);
                                            end                                
                                        end
                                     
                                end
                                
                            case 'Velocity'
                                d=append(b,{' (mm/sec)'});
                               c=app.AxisButtonGroup.SelectedObject.Text;
                                switch c
                                    case 'X'
                                        for i=1:length(a)
                                            t=a(i).Text;                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.vx(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.vx(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.vx(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.vx(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.vx(5,:);
                                            end                                
                                        end
                                    case 'Y'
                                        for i=1:length(a)
                                            t=a(i).Text;                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.vy(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.vy(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.vy(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.vy(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.vy(5,:);
                                            end                                
                                        end
                                    case 'Z'
                                        for i=1:length(a)
                                            t=a(i).Text;                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.vz(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.vz(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.vz(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.vz(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.vz(5,:);
                                            end                                
                                        end
                                end
                            case 'Acceleration'
                                d=append(b,{' (mm/sec^2)'});
                                c=app.AxisButtonGroup.SelectedObject.Text;
                                switch c
                                    case 'X'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.ax(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.ax(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.ax(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.ax(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.ax(5,:);
                                            end                                
                                        end
                                    case 'Y'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.ay(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.ay(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.ay(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.ay(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.ay(5,:);
                                            end                                
                                        end
                                    case 'Z'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.az(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.az(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.az(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.az(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.az(5,:);
                                            end                                
                                        end
                                end
                            case 'Angular Velocity'
                                d=append(b,{' (deg/sec)'});
                                c=app.AxisButtonGroup.SelectedObject.Text;
                                switch c
                                    case 'X'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.wx(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.wx(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.wx(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.wx(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.wx(5,:);
                                            end                                
                                        end
                                    case 'Y'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.wy(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.wy(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.wy(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.wy(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.wy(5,:);
                                            end                                
                                        end
                                    case 'Z'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.wz(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.wz(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.wz(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.wz(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.wz(5,:);
                                            end                                
                                        end
                                end
                            case 'Angular Acceleration'
                                d=append(b,{' (deg/sec^2)'});
                                c=app.AxisButtonGroup.SelectedObject.Text;
                                switch c
                                    case 'X'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.lx(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.lx(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.lx(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.lx(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.lx(5,:);
                                            end                                
                                        end
                                    case 'Y'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.ly(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.ly(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.ly(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.ly(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.ly(5,:);
                                            end                                
                                        end
                                    case 'Z'
                                        for i=1:length(a)
                                            t=a(i).Text;
                                            
                                            g(i)={a(i).Text};
                                            if t=='Link1'
                                                p(i,:)=app.lz(1,:);
                                            end
                                            if t=='Link2'
                                                p(i,:)=app.lz(2,:);
                                            end
                                            if t=='Link3'
                                                p(i,:)=app.lz(3,:);
                                            end
                                            if t=='Link4'
                                                p(i,:)=app.lz(4,:);
                                            end
                                            if t=='Link5'
                                                p(i,:)=app.lz(5,:);
                                            end                                
                                        end
                                end
                        end                    
            end
            tp(g,p,app.ts,d)            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            close(figure(1))
            simulating(app.pj',app.px(5,:),app.py(5,:),app.pz(5,:))  
            app.UploadButton.Enable='on';
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            a=arduino();
            s1o=servo(a,'D3');
            s2o=servo(a,'D4');
            s3o=servo(a,'D5');
            s4o=servo(a,'D6');
            s5o=servo(a,'D7');
            s6o=servo(a,'D8');
            writePosition(s1o,0.5);
            writePosition(s2o,0.53);
            writePosition(s3o,0.94);
            writePosition(s4o,0);
            writePosition(s5o,0.56);
            writePosition(s6o,0);
            b=app.pj;
            for i=1:length(app.ts)
            writePosition(s1o,(b(1,i)+90)/180);
            writePosition(s2o,(b(2,i)+95)/180);
            writePosition(s3o,(170-b(3,i))/180);
            writePosition(s4o,b(4,i)/180);
            writePosition(s5o,(b(5,i)+90)/180);
            pause(0.01);
            end
            clear a s1o s2o s3o s4o s5o s6o
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TrajectoryPlanningLabel = uilabel(app.GridLayout);
            app.TrajectoryPlanningLabel.Text = 'Trajectory Planning';
            app.ChoosethetrajectorytechniqueButtonGroup = uibuttongroup(app.GridLayout);
            app.ChoosethetrajectorytechniqueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosethetrajectorytechniqueButtonGroupSelectionChanged, true);
            app.ChoosethetrajectorytechniqueButtonGroup.Title = 'Choose the trajectory technique';
            app.JointSpaceButton = uiradiobutton(app.ChoosethetrajectorytechniqueButtonGroup);
            app.JointSpaceButton.Text = 'Joint Space';
            app.JointSpaceButton.Value = true;
            app.CartesianSpaceButton = uiradiobutton(app.ChoosethetrajectorytechniqueButtonGroup);
            app.CartesianSpaceButton.Text = 'Cartesian Space';
            app.StartPointLabel = uilabel(app.GridLayout);
            app.StartPointLabel.Text = 'Start Point';
            app.GoalPointLabel = uilabel(app.GridLayout);
            app.GoalPointLabel.Text = 'Goal Point';
            app.q1Label = uilabel(app.GridLayout);
            app.q1Label.Text = 'q1';
            app.TotalTimeLabel = uilabel(app.GridLayout);
            app.TotalTimeLabel.Text = 'Total Time';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_7 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_10 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_11 = uieditfield(app.GridLayout, 'numeric');
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ShowgraphsButton = uibutton(app.GridLayout, 'push');
            app.ShowgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowgraphsButtonPushed, true);
            app.ShowgraphsButton.Enable = 'off';
            app.ShowgraphsButton.Text = 'Show graphs';
            app.q1Label_2 = uilabel(app.GridLayout);
            app.q1Label_2.Text = 'q1';
            app.q2Label = uilabel(app.GridLayout);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.GridLayout);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.GridLayout);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.GridLayout);
            app.q5Label.Text = 'q5';
            app.q2Label_2 = uilabel(app.GridLayout);
            app.q2Label_2.Text = 'q2';
            app.q3Label_2 = uilabel(app.GridLayout);
            app.q3Label_2.Text = 'q3';
            app.q4Label_2 = uilabel(app.GridLayout);
            app.q4Label_2.Text = 'q4';
            app.q5Label_2 = uilabel(app.GridLayout);
            app.q5Label_2.Text = 'q5';
            app.SimulateButton = uibutton(app.GridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate';
            app.XsLabel = uilabel(app.GridLayout);
            app.XsLabel.Visible = 'off';
            app.XsLabel.Text = 'X(s)';
            app.UploadButton = uibutton(app.GridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Enable = 'off';
            app.UploadButton.Text = 'Upload';
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout);
            app.PositionButton = uiradiobutton(app.ButtonGroup_2);
            app.PositionButton.Text = 'Position';
            app.PositionButton.Value = true;
            app.VelocityButton = uiradiobutton(app.ButtonGroup_2);
            app.VelocityButton.Text = 'Velocity';
            app.AccelerationButton = uiradiobutton(app.ButtonGroup_2);
            app.AccelerationButton.Text = 'Acceleration';
            app.AngularVelocityButton = uiradiobutton(app.ButtonGroup_2);
            app.AngularVelocityButton.Enable = 'off';
            app.AngularVelocityButton.Visible = 'off';
            app.AngularVelocityButton.Text = 'Angular Velocity';
            app.AngularAccelerationButton = uiradiobutton(app.ButtonGroup_2);
            app.AngularAccelerationButton.Enable = 'off';
            app.AngularAccelerationButton.Visible = 'off';
            app.AngularAccelerationButton.Text = 'Angular Acceleration';
            app.YsLabel = uilabel(app.GridLayout);
            app.YsLabel.Visible = 'off';
            app.YsLabel.Text = 'Y(s)';
            app.ZsLabel = uilabel(app.GridLayout);
            app.ZsLabel.Visible = 'off';
            app.ZsLabel.Text = 'Z(s)';
            app.TotalTimeLabel_2 = uilabel(app.GridLayout);
            app.TotalTimeLabel_2.Visible = 'off';
            app.TotalTimeLabel_2.Text = 'Total Time';
            app.EditField_12 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_12.Enable = 'off';
            app.EditField_12.Visible = 'off';
            app.EditField_13 = uieditfield(app.GridLayout, 'text');
            app.EditField_13.Enable = 'off';
            app.EditField_13.Visible = 'off';
            app.EditField_13.Value = '250';
            app.EditField_14 = uieditfield(app.GridLayout, 'text');
            app.EditField_14.Enable = 'off';
            app.EditField_14.Visible = 'off';
            app.EditField_14.Value = '150+50*cos(s/50)';
            app.EditField_15 = uieditfield(app.GridLayout, 'text');
            app.EditField_15.Enable = 'off';
            app.EditField_15.Visible = 'off';
            app.EditField_15.Value = '50*sin(s/50)';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.CubicButton = uiradiobutton(app.ButtonGroup);
            app.CubicButton.Text = 'Cubic';
            app.CubicButton.Value = true;
            app.CycloidalButton = uiradiobutton(app.ButtonGroup);
            app.CycloidalButton.Text = 'Cycloidal';
            app.ParabolicButton = uiradiobutton(app.ButtonGroup);
            app.ParabolicButton.Text = 'Parabolic';
            app.ChooseButtonGroup = uibuttongroup(app.GridLayout);
            app.ChooseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseButtonGroupSelectionChanged, true);
            app.ChooseButtonGroup.Title = 'Choose';
            app.JointButton = uiradiobutton(app.ChooseButtonGroup);
            app.JointButton.Text = 'Joint';
            app.JointButton.Value = true;
            app.LinkButton = uiradiobutton(app.ChooseButtonGroup);
            app.LinkButton.Text = 'Link';
            app.stLabel = uilabel(app.GridLayout);
            app.stLabel.Visible = 'off';
            app.stLabel.Text = 's(t)';
            app.EditField_16 = uieditfield(app.GridLayout, 'text');
            app.EditField_16.Enable = 'off';
            app.EditField_16.Visible = 'off';
            app.EditField_16.Value = '2*pi*(6*t^2 - 0.8*t^3)';
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Joint1Node = uitreenode(app.Tree);
            app.Joint1Node.Text = 'Joint1';
            app.Joint2Node = uitreenode(app.Tree);
            app.Joint2Node.Text = 'Joint2';
            app.Joint3Node = uitreenode(app.Tree);
            app.Joint3Node.Text = 'Joint3';
            app.Joint4Node = uitreenode(app.Tree);
            app.Joint4Node.Text = 'Joint4';
            app.Joint5Node = uitreenode(app.Tree);
            app.Joint5Node.Text = 'Joint5';
            app.AxisButtonGroup = uibuttongroup(app.GridLayout);
            app.AxisButtonGroup.Enable = 'off';
            app.AxisButtonGroup.Title = 'Axis';
            app.AxisButtonGroup.Visible = 'off';
            app.XButton = uiradiobutton(app.AxisButtonGroup);
            app.XButton.Enable = 'off';
            app.XButton.Visible = 'off';
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.AxisButtonGroup);
            app.YButton.Enable = 'off';
            app.YButton.Visible = 'off';
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.AxisButtonGroup);
            app.ZButton.Enable = 'off';
            app.ZButton.Visible = 'off';
            app.ZButton.Text = 'Z';
            app.RrLabel = uilabel(app.GridLayout);
            app.RrLabel.Visible = 'off';
            app.RrLabel.Text = 'R(r)';
            app.EditField_17 = uieditfield(app.GridLayout, 'text');
            app.EditField_17.Enable = 'off';
            app.EditField_17.Visible = 'off';
            app.EditField_17.Value = '90';
            app.PrLabel = uilabel(app.GridLayout);
            app.PrLabel.Visible = 'off';
            app.PrLabel.Text = 'P(r)';
            app.YrLabel = uilabel(app.GridLayout);
            app.YrLabel.Visible = 'off';
            app.YrLabel.Text = 'Y(r)';
            app.EditField_18 = uieditfield(app.GridLayout, 'text');
            app.EditField_18.Enable = 'off';
            app.EditField_18.Visible = 'off';
            app.EditField_18.Value = '-30';
            app.EditField_19 = uieditfield(app.GridLayout, 'text');
            app.EditField_19.Enable = 'off';
            app.EditField_19.Visible = 'off';
            app.EditField_19.Value = '11.31*sin(r/50)';
            app.rtLabel = uilabel(app.GridLayout);
            app.rtLabel.Visible = 'off';
            app.rtLabel.Text = 'r(t)';
            app.EditField_20 = uieditfield(app.GridLayout, 'text');
            app.EditField_20.Enable = 'off';
            app.EditField_20.Visible = 'off';
            app.EditField_20.Value = '2*pi*(6*t^2 - 0.8*t^3)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end